classdef keypad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NUMBEREditField       matlab.ui.control.EditField
        NUMBEREditFieldLabel  matlab.ui.control.Label
        InsertagainButton     matlab.ui.control.Button
        DIALButton            matlab.ui.control.Button
        Button_12             matlab.ui.control.Button
        Button_11             matlab.ui.control.Button
        Button_10             matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
    end

    properties (Access = public)
        num
        fhigh
        flow
        i
        j
        duration
        fs
        t
    end    
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.num='+'; 
            app.fhigh=[1209 , 1336 , 1477];
            app.flow=[697 , 770 , 852 , 941];
            app.i=1;
            app.j=1;
            app.duration=0.1;
            app.fs=8000;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.num=[app.num , '1'];
            app.NUMBEREditField.Value = app.num;
            app.i=1;
            app.j=1;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.num=[app.num,'2'];
            app.NUMBEREditField.Value = app.num    ;   
            app.i=2;
            app.j=1;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)  
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.num=[app.num,'3'];
            app.NUMBEREditField.Value = app.num;
            app.i=3;
            app.j=1;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.num=[app.num,'4'];
            app.NUMBEREditField.Value = app.num;
            app.i=1;
            app.j=2;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs) 
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.num=[app.num,'5'];
            app.NUMBEREditField.Value = app.num;
            app.i=2;
            app.j=2;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs) 
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.num=[app.num,'6'];
            app.NUMBEREditField.Value = app.num;
            app.i=3;
            app.j=2;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)  
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.num=[app.num,'7'];
            app.NUMBEREditField.Value = app.num;
            app.i=1;
            app.j=3;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs) 
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.num=[app.num,'8'];
            app.NUMBEREditField.Value = app.num;
            app.i=2;
            app.j=3;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)  
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.num=[app.num,'9'];
            app.NUMBEREditField.Value = app.num;
            app.i=3;
            app.j=3;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)  
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.num=[app.num,'*'];
            app.NUMBEREditField.Value = app.num;
            app.i=1;
            app.j=4;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)  
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.num=[app.num,'0'];
            app.NUMBEREditField.Value = app.num;
            app.i=2;
            app.j=4;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs)  
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.num=[app.num,'#'];
            app.NUMBEREditField.Value = app.num;
            app.i=3;
            app.j=4;
            app.t=[0:1/app.fs:app.duration];
            y=sin(2*pi*app.fhigh(app.i)*app.t)+sin(2*pi*app.flow(app.j)*app.t);
            sound(y,app.fs) 
        end

        % Button pushed function: DIALButton
        function DIALButtonPushed(app, event)
            %assignin('base','num',app.num)
            %assignin('base','fhigh',app.fhigh)
            %assignin('base','flow',app.flow)
            %assignin('base','duration',app.duration)
            main
        end

        % Button pushed function: InsertagainButton
        function InsertagainButtonPushed(app, event)
            app.num='+';
            app.NUMBEREditField.Value = '';
            
        end

        % Value changing function: NUMBEREditField
        function NUMBEREditFieldValueChanging(app, event)
           %app.NUMBEREditField.Value = app.num
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '*';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '0';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '#';
            app.DIALButton = uibutton(app.UIFigure, 'push');
            app.DIALButton.ButtonPushedFcn = createCallbackFcn(app, @DIALButtonPushed, true);
            app.DIALButton.Text = 'DIAL';
            app.InsertagainButton = uibutton(app.UIFigure, 'push');
            app.InsertagainButton.ButtonPushedFcn = createCallbackFcn(app, @InsertagainButtonPushed, true);
            app.InsertagainButton.Text = 'Insert again';
            app.NUMBEREditFieldLabel = uilabel(app.UIFigure);
            app.NUMBEREditFieldLabel.Text = 'NUMBER';
            app.NUMBEREditField = uieditfield(app.UIFigure, 'text');
            app.NUMBEREditField.ValueChangingFcn = createCallbackFcn(app, @NUMBEREditFieldValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = keypad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end